classdef SR300_App_Beta1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    properties (Access = public )
%This program shows options for paramters with the SR300
%requires optionstable.mat
%        RGBfig         matlab.ui.Figure %FOR THE rgb CAMERA frame
        
        TabGroup                     matlab.ui.container.TabGroup
        TabCamSelected                      matlab.ui.container.Tab
        paramListBox                 matlab.ui.control.ListBox
        listBoxMaxHeight %for calculating the size of the window
        setselectiontodefaultButton  matlab.ui.control.Button
        saveAsButton                 matlab.ui.control.Button
        paramfileName    %name of the last file saved
        folderSaved
        loadSavedParamButton         matlab.ui.control.Button
        warningMsgButtons            matlab.ui.control.Label
        setallparameterstodefaultvaluesButton  matlab.ui.control.Button
        allstreamButton              matlab.ui.control.StateButton
        %colorstreamButton            matlab.ui.control.StateButton
        colorstreamButton            matlab.ui.control.Button
        pointcloudButton             matlab.ui.control.StateButton
        depthstreamButton            matlab.ui.control.StateButton
        numOfButtons
        colorcalibrationButton  matlab.ui.control.Button
        %Ivcam objects
        ivcampresetDropDownLabel     matlab.ui.control.Label
        ivcampresetDropDown          matlab.ui.control.DropDown
        %variables for loading ivcam presets
        ivcamopts
        ivcamoptvals
        ivcampresets
        ivcamPanel                  matlab.ui.container.Panel
        minMaxDefPanel              matlab.ui.container.Panel
        ivcamPanelHeight 
        %slider creation
        SliderMinMaxText           matlab.ui.control.Label
        SliderMinValue             matlab.ui.control.Label
        SliderMaxValue             matlab.ui.control.Label
        SliderText                 matlab.ui.control.Label
        SliderLabel                matlab.ui.control.Label
        SliderITSELF               matlab.ui.control.Slider
        ValueSpinnerLabel          matlab.ui.control.Label
        ValueSpinner               matlab.ui.control.Spinner
        defaultValueBoxLabel       matlab.ui.control.Label
        defaultValueBox            matlab.ui.control.Label
        CameraDropDownLabel        matlab.ui.control.Label
        CameraDropDown             matlab.ui.control.DropDown
        descriptionTextArea          matlab.ui.control.TextArea
        descriptionTextAreaLabel     matlab.ui.control.Label
        appfullPath    %char vector with the app's full path
        stepsDisplayed % for saving array of 11 elements to be displayed on slider
        optionsTitlesCamera %titles, descriptions, and parameters
        
        warningNoCam
        %struct with options for connected cameras. camera field SR300_1 F200_1 F200_2 etc is a cell array with all options
        %syntax: app.selCamOptTable.SR300_1 , SR300_1 being the first camera connected (matches names on
        selCamOptTable
        OptTableComplete %has paramters not available on teh selected cameras (just for getting ivcam values retrived from the cam)
        presentCamSelecOptTable %friendlier way of selecting parameters on the table
        
        %num array for defaul, min, and max values
        minMaxDefaultStepValues %increases increment/decrement step to 10
        connctdShortCamNames % all connnected cameras - cell array ex: {'SR300' 'F200'}     
        %camera %name of the camera
        disFromBottomMinMaxBox %distance from botton of min max box
        tabSelection %selected tab Object; app.tabSelection.Title for string with title
        index
        functionCall %for saving the paramter/function's name
        deviceFriendlyName
%%%%%%% C++ library variables/objects %%%%%%%%%%%%%%%%%%
        dev %pointer - camera
        opt %from 0 to 68 (corresponds to functions' names
        dev_opt_current_value %t
        %SliderTitleBottomPosition %height of slider title from the bottom
        listBoxPosition
        rsoptions
        width
        height
        fps
        ctx
        rsEnum
        err
        depth_intrin
        color_intrin
        depth_to_color
        methodinfo
        structs
        ThunkLibName
        scale
        ardobj
        %variables for selecting camera
        devIdx
        deviceCount
        selectdev %index of selected camera
        %timer variables
        timercolor
        timerdepth
        timerpcl
        timerall
        %axes and figure handles
        figcolor
        figdepth
        figpcl
        figall
        axcolor
        axdepth
        axpcl
        axall
        %stream snapshot counts
        countcolor
        countdepth
        countpcl
        %color calibration image and coordinates and figures and axes
        calimage
        colorcoords
        figcalorig
        figcalcorr
        axcalorig
        axcalcorr
    end

    methods (Access = private)

% >>>>>>>>> initializeVariables >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function initializeVariables(app)
            app.opt = 0; %starts with Contrast
            app.width = 640; 
            app.height = 480;
            app.fps = 30;
            app.deviceCount = 0;
            %app.ardobj = arduino;
            %app.axis1 = servo(app.ardobj,'D2');
            %app.axis2 = servo(app.ardobj,'D3');
            app.warningNoCam = figure('Name','dummy','Visible','off');%initializing warning fig for NO camera available;
  
%             try 
                if ~libisloaded('realsense')
                    loadlibrary('realsense.dll', 'rsutil.h', ...
                                'addheader','rs.h', ...
                                'mfilename', 'realsense');
                end
                            
                [app.methodinfo, app.structs, app.rsEnum, app.ThunkLibName] = realsense;
                [app.ctx, app.err] = calllib('realsense', 'rs_create_context', 11201, rs_error);
                rs_check_error(app.err);
                
                %new code to get number of cameras connected and make device objects and initialize each
                [app.deviceCount, app.err] = calllib('realsense', 'rs_get_device_count', app.ctx, rs_error());
                rs_check_error(app.err);
%             catch
%                 error('Please run MATLAB as an admin to load realsense.dll.');
%             end
            
            if app.deviceCount < 1
                app.warningNoCam= warndlg('No camera found. Exiting the app.');
            else
                for i = 1:app.deviceCount
                    app.devIdx = i - 1;
                    [app.dev{i}, app.err] = calllib('realsense', 'rs_get_device',...
                        app.ctx, app.devIdx, rs_error);
                    rs_check_error(app.err);
                    
                    [app.deviceFriendlyName{i}, app.err] = calllib('realsense', 'rs_get_device_name', ...
                        app.dev{i}, rs_error());
                    rs_check_error(app.err);
    
                    %% RGB stream
                    [~, app.err] = calllib('realsense', 'rs_enable_stream',...
                        app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.width, app.height,...
                        app.rsEnum.rs_format.RS_FORMAT_RGB8, app.fps, rs_error);
                    rs_check_error(app.err);
                   
                    
    %                 [~, app.err] = calllib('realsense', 'rs_enable_stream',...
    %                     app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.width, app.height,...
    %                     app.rsEnum.rs_format.RS_FORMAT_RGB8, app.fps, rs_error);
    %                 rs_check_error(app.err);
    
                    [~, app.err] = calllib('realsense', 'rs_enable_stream',...
                        app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.width, app.height,...
                        app.rsEnum.rs_format.RS_FORMAT_Z16, app.fps, rs_error);
                    rs_check_error(app.err);
                    
                    [~, app.err] = calllib('realsense', 'rs_start_device', app.dev{i}, rs_error);
                    rs_check_error(app.err);
                    
                    app.depth_intrin{i} = libstruct('rs_intrinsics', struct());
                    
                    [~, app.depth_intrin{i}, app.err] = calllib('realsense', 'rs_get_stream_intrinsics', ...
                        app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.depth_intrin{i}, rs_error);
                    rs_check_error(app.err);
                    
                    app.color_intrin{i} = libstruct('rs_intrinsics', struct());
                    [~, app.color_intrin{i}, app.err] = calllib('realsense', 'rs_get_stream_intrinsics', ...
                        app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.color_intrin{i}, rs_error);
                    rs_check_error(app.err);
                    
                    app.depth_to_color{i} = libstruct('rs_extrinsics', struct());
                    [~, app.depth_to_color{i}, app.err] = calllib('realsense', 'rs_get_device_extrinsics', ...
                        app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.depth_to_color{i}, rs_error);
                    rs_check_error(app.err);
                    
                    [app.scale{i}, app.err] = calllib('realsense', 'rs_get_device_depth_scale',...
                        app.dev{i}, rs_error);
                    rs_check_error(app.err);
                    
                    %x, y are dummy variables
                    app.timercolor{i} = timer('TimerFcn', @(x, y) RGB_stream(app, x, y),...
                        'ExecutionMode', 'fixedRate', 'Period', 0.033, 'Name', 'timercolor');
                    app.figcolor{i} = figure('Name',num2str(i));
                    %app.figcolor{i} = figure('Name',num2str(i),'Visible','off');
                    close(app.figcolor{i});
                end
                
                if isempty(app.dev) %device was disconnected before leaving matlab but app was run again
                    app.warningNoCam= warndlg('No camera found. Exiting the app.');
                else % code continues executing
                    app.selectdev = 1;
                    app.index = 1;
                    getCamShortName(app);%separates the camera nickname ("SR300", for ex) from human-friendly name
                    
                    %initialize variables for ivcam presets
                    app.ivcamopts = [18, 19, 20, 21, 22, 23, 24, 25, 26, 27, 12, 13, 15, 16, 14];
                    app.ivcampresets = load('ivcampresets.mat'); 
                    %app.ivcamoptvals = app.ivcampresets.ivcampresets(1)
                    %initialize stream snapshot variables
                    app.countcolor = 0;
                    app.countdepth = 0;
                    app.countpcl = 0;
                    
                    %timers for stream display scripts. 1/3 == 0.033 (timers won't take smaller values than one milisecond)
                    
                    
                    app.timerdepth = timer('TimerFcn', @(x, y) depth(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 0.033, 'Name', 'timerdepth');
                    app.timerpcl = timer('TimerFcn', @(x, y) pcl(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 0.033, 'Name', 'timerpcl');
                    app.timerall = timer('TimerFcn', @(x, y) allcam(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 0.033, 'Name', 'timerall');
        
                    %hide figure 1
                    set(figure(1),'Visible','off');
                    drawnow;
                    
                    %load calibration coordinates
                    testAndChangePathIfNeeded(app); %changes folder if it's not the app's and adds colorcalibration folder
                    
                    app.colorcoords = load('colorcoords.mat');
                end

                
            end
            
            
        end
%>>>>>>>>> END of initializeVariables
%>>>>>>>>> GetOptions >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function GetOptions(app)
            %updates spinner/slider with current value retrieved from camera for the selected parameter
            [app.dev_opt_current_value, ~, app.err] = calllib('realsense', 'rs_get_device_option',...
                app.dev{app.selectdev}, app.opt, rs_error);
            rs_check_error(app.err);
            
            app.ValueSpinner.Value = app.dev_opt_current_value;
            app.SliderITSELF.Value = app.ValueSpinner.Value;
            app.presentCamSelecOptTable{app.index,6} = ...
                app.dev_opt_current_value; %updates present value ...
                                           %in selected cam's options table
         end
%>>>>>>>>>> END of GetOptioons

%>>>>>>>>> buildivcamPanel >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildivcamPanel(app)
           for i = 1:app.deviceCount
                app.ivcamPanel(i) = uipanel(app.TabCamSelected(i));
                app.ivcamPanel(i).FontWeight = 'bold';
                app.ivcamPanel(i).Title = 'Ivcam presets';
                distFromBottom = app.listBoxPosition(2)-250; %relative to list box and tab bottom
                widthIvcam = app.TabGroup.SelectedTab.Position(3)-100;
                app.ivcamPanel(i).Position = [app.listBoxPosition(1) distFromBottom widthIvcam 230]; 
           end
           
           buildIvcamItem(app);
           app.ivcamPanelHeight = app.ivcamPanel(1).Position(4);
           buildCompleteParamList(app);
                
%             app.ivcamPanel = uipanel(app.tabSelection);
%             app.ivcamPanel.FontWeight = 'bold';
%             app.ivcamPanel.Title = 'Ivcam presets';
%             %titleIvcam = app.presentCamSelecOptTable{app.index,3}
%             distFromBottom = app.listBoxPosition(2)-250; %relative to list box and tab bottom
%             widthIvcam = app.TabGroup.SelectedTab.Position(3)-100;
%             app.ivcamPanel.Position = [app.listBoxPosition(1) distFromBottom widthIvcam 230];
%             buildIvcamItem(app);
            
% for i=1:(length(app.ivcampresets(:,1))-1)
%             app.ivcamShowPresetLabel(i) = uilabel(app.ivcamPanel);
%             app.ivcamShowPresetLabel(i).HorizontalAlignment = 'right';
%             app.ivcamShowPresetLabel(i).FontWeight = 'bold';
%             app.ivcamShowPresetLabel(i).Position = [5+j 70 100 15];
%             app.ivcamShowPresetLabel(i).Text = { app.ivcampresets{1,i}; '';''; app.ivcampresetDropDown.Items{1,i+1}};
% 	j = j + 
% 
% end
        end
%>>>>>>>>>> END of buildivcamPanel

%>>>>>>>>> buildBottomButtons >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildBottomButtons(app)
                  % Create setallparameterstodefaultvaluesButton
            %app.bottombuttonsPosition = [3 app.UIFigure.Position(2) app.UIFigure.Position(3) app.UIFigure.Position(4)]
            
            distFromBottom = 10;
            buttonsHeight=22;
            distBetweenButtons = 15;
               %% app.TabCamSelected(i)
            for dev_number = 1:app.deviceCount
                app.setallparameterstodefaultvaluesButton = uibutton(app.TabCamSelected(dev_number), 'push');
                app.setallparameterstodefaultvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @setallparameterstodefaultvaluesButtonPushed, true);
                app.setallparameterstodefaultvaluesButton.FontColor = [1 0 0];
                app.setallparameterstodefaultvaluesButton.Position = [17.5 distFromBottom 150 buttonsHeight];
                app.setallparameterstodefaultvaluesButton.Text = 'default for all parameters';
                
                % Create colorcalibrationButton
                previousButPos = app.setallparameterstodefaultvaluesButton.Position;
                presentButLeftPos = previousButPos(1) + previousButPos(3) + distBetweenButtons;
                app.colorcalibrationButton = uibutton(app.TabCamSelected(dev_number), 'push');
                app.colorcalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @colorcalibrationButtonPushed, true);
                app.colorcalibrationButton.Position = [presentButLeftPos distFromBottom 100 buttonsHeight];
                app.colorcalibrationButton.Text = 'color calibration';
                %clear previousButPos;
    
                % Create colorstreamButton
                previousButPos = app.colorcalibrationButton.Position;
                presentButLeftPos = previousButPos(1) + previousButPos(3) + distBetweenButtons;
                
                app.colorstreamButton = uibutton(app.TabCamSelected(dev_number), 'push');
                %app.colorstreamButton = uibutton(app.TabGroup, 'push');
                %app.colorstreamButton.ValueChangedFcn = createCallbackFcn(app, @colorstreamButtonValueChanged, true);
                app.colorstreamButton.ButtonPushedFcn = createCallbackFcn(app, @colorstreamButtonValueChanged, true);
                app.colorstreamButton.Text = 'color stream';
                app.colorstreamButton.Position = [presentButLeftPos distFromBottom  80 buttonsHeight];
                
                % Create depthstreamButton
                previousButPos = app.colorstreamButton.Position;
                presentButLeftPos = previousButPos(1) + previousButPos(3) + distBetweenButtons;
                app.depthstreamButton = uibutton(app.TabCamSelected(dev_number), 'state');
                app.depthstreamButton.ValueChangedFcn = createCallbackFcn(app, @depthstreamButtonValueChanged, true);
                app.depthstreamButton.Text = 'depth stream';
                app.depthstreamButton.Position = [presentButLeftPos distFromBottom  80 buttonsHeight];
                
                % Create allstreamButton
                previousButPos = app.depthstreamButton.Position;
                presentButLeftPos = previousButPos(1) + previousButPos(3) + distBetweenButtons;
                app.allstreamButton = uibutton(app.TabCamSelected(dev_number), 'state');
                app.allstreamButton.ValueChangedFcn = createCallbackFcn(app, @allstreamButtonValueChanged, true);
                app.allstreamButton.Text = 'all stream';
                app.allstreamButton.Position = [presentButLeftPos distFromBottom  80 buttonsHeight];
    
                % Create pointcloudButton
                previousButPos = app.allstreamButton.Position;
                presentButLeftPos = previousButPos(1) + previousButPos(3) + distBetweenButtons;
                app.pointcloudButton = uibutton(app.TabCamSelected(dev_number), 'state');
                app.pointcloudButton.ValueChangedFcn = createCallbackFcn(app, @pointcloudButtonValueChanged, true);
                app.pointcloudButton.Text = 'point cloud';
                app.pointcloudButton.Position = [presentButLeftPos distFromBottom  80 buttonsHeight];
                
                %warning about calibration
                app.warningMsgButtons = uilabel(app.TabCamSelected(dev_number));
                app.warningMsgButtons.HorizontalAlignment = 'left';
                app.warningMsgButtons.FontWeight = 'normal';
                app.warningMsgButtons.Text = 'Color Calibration: run the color stream at least once before clicking on color calibration';
                textSize = length(app.warningMsgButtons.Text)*6;
                app.warningMsgButtons.Position = [18 (distFromBottom+buttonsHeight+7) textSize 15];
                
                % Create saveAsButton
                %previousButPos = app.allstreamButton.Position;
                presentButLeftPos = app.pointcloudButton.Position(1)-20;
                saveDistFromBottom = distFromBottom + 23;
                %previousButPos(1) + previousButPos(3) + distBetweenButtons;
                app.saveAsButton = uibutton(app.TabCamSelected(dev_number), 'push');
                app.saveAsButton.VerticalAlignment = 'center';
                app.saveAsButton.ButtonPushedFcn = createCallbackFcn(app, @saveAsButtonButtonPushed, true);
                app.saveAsButton.Text = 'Save Current Parameters';
                app.saveAsButton.Position = [presentButLeftPos saveDistFromBottom 150 (buttonsHeight-2)];
                
                
                % Create loadSavedParamButton
                %previousButPos = app.allstreamButton.Position;
                presentButLeftPos = app.saveAsButton.Position(1)-200;
                saveDistFromBottom = distFromBottom + 23;
                %previousButPos(1) + previousButPos(3) + distBetweenButtons;
                app.loadSavedParamButton = uibutton(app.TabCamSelected(dev_number), 'push');
                app.loadSavedParamButton.VerticalAlignment = 'center';
                app.loadSavedParamButton.ButtonPushedFcn = createCallbackFcn(app, @loadSavedParamButtonPushed, true);
                app.loadSavedParamButton.Text = 'Load Saved Parameters';
                app.loadSavedParamButton.Position = [presentButLeftPos saveDistFromBottom 150 (buttonsHeight-2)];
          
            end %for loop creating group of buttons per
            
        end
%>>>>>>>>>> END of buildBottomButtons

%>>>>>>>>>>  buildSelection >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        % app required parameter
        % minMax is a 2 element horizontal integers vector 
                % 1st element = min 
                % 2nd = MAX values for parameter
        % description is a cell-array with comments about the parameter
            %the cell-array has syntax {'blah blah blah';'blah blah blah'} - the ; (semicolon) for newline
        % discreteOrNot can be any number  for discrete slider or 0 for nondiscrete
        % stepsDisplayed is a function that generates a vector with up to 10 elements
        % WORKS FOR PARAMATERS WITH STEP OF 1 (positive integer)
        
%        function buildSelection(app, title, index)
        function buildSelection(app)
            erasePreviousSelection(app); % clears previous slider and number boxes
            %creates array of doubles min, max, default, and step parameter values from cell array
            app.minMaxDefaultStepValues = cell2mat([app.presentCamSelecOptTable(app.index,7);...
                app.presentCamSelecOptTable(app.index,9); app.presentCamSelecOptTable(app.index,8);...
                app.presentCamSelecOptTable(app.index,10)]);
            getValuesDispBySlider(app); %produces array with values to be shown by slider
            buildMinMaxBoxes(app);%shows boxes with min and max possible values
            createParameterSlider(app); %shows new slider
            createSpinner(app); % shows pinner
            createDescription(app);
            GetOptions(app);%interfaces with hardware
        end
%>>>>>>>>>>  END of buildSelection

%>>>>>>>>>>  erasePreviousSelection >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function erasePreviousSelection(app) %clears previous selection
            delete(app.minMaxDefPanel);
            delete(app.SliderMinValue);
            delete(app.SliderMinMaxText);
            delete(app.SliderMaxValue);
            delete(app.SliderText);
            delete(app.SliderLabel);
            delete(app.SliderITSELF);
            %delete(app.ValueSpinnerLabel);
            %delete(app.ValueSpinner);
            delete(app.descriptionTextArea);
            delete(app.descriptionTextAreaLabel);
            clear app.minMaxDefaultStepValues;
            clear app.stepsDisplayed;
        end
%>>>>>>>>>>  END of erasePreviousSelection

%>>>>>>>>>>  stepsDisplayed >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
%calcultes which and how many steps the slider has
function getValuesDispBySlider(app)
            rangeBetweenMinMax = abs(app.minMaxDefaultStepValues(2)-app.minMaxDefaultStepValues(1));
            
            if rangeBetweenMinMax >10  % if abs(max - min) > 10, defines 10 steps
                step = (app.minMaxDefaultStepValues(2)-app.minMaxDefaultStepValues(1))/10;
                app.stepsDisplayed = zeros(1,11);
                app.stepsDisplayed(1) = app.minMaxDefaultStepValues(1); %initializes smallest value
                app.stepsDisplayed(11) = app.minMaxDefaultStepValues(2); %initializes smallest value
                for i = 2:10 %initializes slider min < (slider values) < max 
                   app.stepsDisplayed(i) = round(step + app.stepsDisplayed(i-1));
                end
                app.stepsDisplayed(6) = app.minMaxDefaultStepValues(3);
                %app.dev_opt_current_value;%center of slide = current value (not necessarily shown)
            else
                app.stepsDisplayed = zeros(1, rangeBetweenMinMax +1);%+1 to make sure the max value will be shown in arrays with 0 values
                app.stepsDisplayed(1) = app.minMaxDefaultStepValues(1);
                app.stepsDisplayed(rangeBetweenMinMax+1) = app.minMaxDefaultStepValues(2); % rangeBetweenMinMax alsp accounts max = 0 adn min < 0      
                for i = 2:rangeBetweenMinMax %initializes slider min < (slider values) < max 
                   app.stepsDisplayed(i) = app.stepsDisplayed(i-1)+1;
                end
            end
        end
%>>>>>>>>>>  END of getValuesDispBySlider

%>>>>>>> buildIvcamItem >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildIvcamItem(app)

            % Create ivcampresetDropDown
            for i=1:app.deviceCount
                app.ivcampresetDropDown(i) = uidropdown(app.ivcamPanel(i));
                app.ivcampresetDropDown(i).Items = {'ShortRange', 'LongRange', 'BackgroundSegmentation', 'GestureRecognition', 'ObjectScanning', 'FaceAnalytics', 'FaceLogin', 'GRCursor', 'Default', 'MidRange', 'IROnly'};
                app.ivcampresetDropDown(i).ValueChangedFcn = createCallbackFcn(app, @ivcampresetDropDownValueChanged, true);
                app.ivcampresetDropDown(i).Position = [20 170 100 25];
                app.ivcampresetDropDown(i).Value = 'ShortRange';
            end
        end

%>>>>>>> buildTabs >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildTabs(app)
            for i = 1:app.deviceCount
                app.TabCamSelected(i) = uitab(app.TabGroup);
                app.TabCamSelected(i).AutoResizeChildren = 'off';
                app.TabCamSelected(i).Title = strcat(strcat...
                    (app.connctdShortCamNames(i),strcat('_',string(i)))); %ex: 'SR300_1' tab name, ...
                                                            %...which matches struct app.selCamOptTable.SR300_1 where
                                                            %data is held
            end
            buildListBox(app);
        end
%>>>>> END of buildTabs

%>>>>>>> buildListBox >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildListBox(app)
            buildAvailabCamParamList(app); %script for getting all parameters for the specific camera
            
            listboxHeight = zeros(1,app.deviceCount);
            
            for i = 1:app.deviceCount
                app.paramListBox(i) = uilistbox(app.TabCamSelected(i));
                app.paramListBox(i).Items = getParListItems(app,i);
                app.paramListBox(i).ValueChangedFcn = ...
                createCallbackFcn(app, @selectedParameter, true);
        	app.paramListBox(i).Position = [20 320 296 (18.2*length(app.paramListBox(i).Items))];
                app.paramListBox(i).Value = {};
                %app.paramListBox
                listboxHeight(i) = app.paramListBox(i).Position(4);
            end
                app.listBoxMaxHeight = max(listboxHeight);
                
            for i=1:app.deviceCount
                app.paramListBox(i).Value = app.paramListBox(i).Items{1};
            end
            app.listBoxPosition = app.paramListBox(1).Position;%[sizes]
        end
%>>>>> END of buildListBox

%>>>>>>>>>> optionTitleReturned >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
    function optionList = getParListItems(app,loopIndex)
        optionTitleReturned = cell(app.selCamOptTable.(app.TabCamSelected(loopIndex).Title)(:,:));
        optionTitleReturned = app.selCamOptTable.(app.TabCamSelected(loopIndex).Title)(1,4);%saves the first item

        for i=2:length(app.selCamOptTable.(app.TabCamSelected(loopIndex).Title)(:,4))%first description has been saved alaready
            strFindCondition = app.selCamOptTable.(app.TabCamSelected(loopIndex).Title){i,4};
            %the following options should be exposed properly out of the listbox
            NOTautoRange = ~contains(strFindCondition, 'auto range');
            NOTframe = ~contains(strFindCondition, 'frame');
            NOTlogger = ~contains(strFindCondition, 'logger');
            NOTfps = ~contains(strFindCondition, 'fps');
            if NOTautoRange && NOTframe && NOTlogger && NOTfps %checks for ivcam presets & slider-unsupported ones
                optionTitleReturned = [optionTitleReturned;app.selCamOptTable.(app.TabCamSelected(loopIndex).Title)(i,4)];
            end
        end
        %optionTitleReturned{end} = 'ivcam presets';
        optionList = optionTitleReturned; 
    end
%>>>>>>>>> % END of optionTitleReturned

%>>>>>>> createDescription >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function createDescription(app)
            app.descriptionTextArea = uitextarea(app.minMaxDefPanel);
            app.descriptionTextArea.Editable = 'off';
            app.descriptionTextArea.BackgroundColor = [0.9412 0.9412 0.9412];
            app.descriptionTextArea.Position = [5 5 228.015625 90];
            app.descriptionTextArea.FontWeight = 'normal';
            app.descriptionTextArea.Value = app.presentCamSelecOptTable(app.index,5);
        end
%>>>>> END of createDescription

%>>>>>>>>> buildMinMaxDefPanel >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildMinMaxDefPanel(app)
            app.minMaxDefPanel = uipanel(app.tabSelection);
            %app.minMaxDefPanel.FontWeight = 'bold';
            %app.minMaxDefPanel.Title = 'Ivcam presets'
            distFromLeft = app.listBoxPosition(1)+app.listBoxPosition(3)+20;%distfrom left + width of list box
            app.minMaxDefPanel.Position = [distFromLeft app.listBoxPosition(2) 240 app.listBoxPosition(4)];
        end
%>>>>>>>>>> END of buildMinMaxDefPanel

%>>>>>>>>> showMinMaxBoxes >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildMinMaxBoxes(app)
            buildMinMaxDefPanel(app);
            app.disFromBottomMinMaxBox = app.minMaxDefPanel.Position(4)-50;
            % Create SliderMinMaxText
            app.SliderMinMaxText = uilabel(app.minMaxDefPanel);
            app.SliderMinMaxText.Position = [5 app.disFromBottomMinMaxBox 28 42];
            app.SliderMinMaxText.Text = {'max'; ''; 'min'};
            
            % for MAX
            app.SliderMaxValue = uilabel(app.minMaxDefPanel);
            app.SliderMaxValue.BackgroundColor = [0.8588 0.9451 0.9882];
            app.SliderMaxValue.HorizontalAlignment = 'right';
            app.SliderMaxValue.FontWeight = 'bold';
            app.SliderMaxValue.Position = [37 (app.disFromBottomMinMaxBox+27) 42 15];
            app.SliderMaxValue.Text = num2str(app.minMaxDefaultStepValues(2));

            % MIN
            app.SliderMinValue = uilabel(app.minMaxDefPanel);
            app.SliderMinValue.BackgroundColor = [0.8588 0.9451 0.9882];
            app.SliderMinValue.HorizontalAlignment = 'right';
            app.SliderMinValue.FontWeight = 'bold';
            app.SliderMinValue.Position = [37 (app.disFromBottomMinMaxBox-2) 42 15];
            app.SliderMinValue.Text = num2str(app.minMaxDefaultStepValues(1));
            
            % Create defaultValueBoxLabel
            app.defaultValueBoxLabel = uilabel(app.minMaxDefPanel);
            app.defaultValueBoxLabel.Position = [102 (app.disFromBottomMinMaxBox+12) 50 42];
            app.defaultValueBoxLabel.FontWeight = 'normal';
            app.defaultValueBoxLabel.HorizontalAlignment = 'center';
            app.defaultValueBoxLabel.Text = {'default'};
            
            % Create defaultValueBox - box at the slider's left-hand side
            app.defaultValueBox = uilabel(app.minMaxDefPanel);
            app.defaultValueBox.BackgroundColor = [0.8588 0.9451 0.9882];
            app.defaultValueBox.HorizontalAlignment = 'right';
            app.defaultValueBox.FontWeight = 'bold';
            app.defaultValueBox.Position = [172 (app.disFromBottomMinMaxBox+27) 42 15];
            app.defaultValueBox.Text = num2str(app.minMaxDefaultStepValues(3));
            
            % Create setselectiontodefaultButton
            app.setselectiontodefaultButton = uibutton(app.minMaxDefPanel, 'push');
            app.setselectiontodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @setselectiontodefaultButtonPushed, true);
            app.setselectiontodefaultButton.Position = [95 app.disFromBottomMinMaxBox-3 135 22];
            app.setselectiontodefaultButton.Text = 'set selection to default';
        end
%>>>>END of showMinMaxBoxes

%>>>>> createParameterSlider >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>    
function createParameterSlider(app) %parent is min Max panel
            % Create SliderLabel
            app.SliderLabel = uilabel(app.tabSelection);
            app.SliderLabel.HorizontalAlignment = 'center';
            app.SliderLabel.FontWeight = 'bold';
            app.SliderLabel.FontSize = 14;
            SliderTitleLeftPosition = app.tabSelection.Position(1)+1;
            SliderTitleBottomPosition = app.TabGroup.SelectedTab.Position(4)-30; %height starts at 1 bottom from uifigure;
            SliderTitleWidthPosition = 10*(length(app.presentCamSelecOptTable{app.index,3})+17);
            app.SliderLabel.Position = [SliderTitleLeftPosition SliderTitleBottomPosition SliderTitleWidthPosition 19];
            paramTitle = app.presentCamSelecOptTable{app.index,3};
            app.SliderLabel.Text = strcat(strcat('library parameter:',{'  '}), paramTitle);
            
            % Create SliderITSELF
            app.SliderITSELF = uislider(app.tabSelection);
            app.SliderITSELF.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SliderITSELF.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SliderITSELF.Limits = [app.minMaxDefaultStepValues(1) app.minMaxDefaultStepValues(2)];
            app.SliderITSELF.Value = app.minMaxDefaultStepValues(3);
            app.SliderITSELF.MajorTicks = app.stepsDisplayed;
            app.SliderITSELF.Orientation = 'vertical';
            app.SliderITSELF.MinorTicks = 1;
            sliderLeftPosition = app.TabGroup.SelectedTab.Position(3)-100;
            app.SliderITSELF.Position = [sliderLeftPosition app.listBoxPosition(2) 3 app.listBoxPosition(4)];
        end
%>>>>> END of createSlider

%>>>>>>> createSpinner >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function createSpinner(app)
            % Create ValueSpinnerLabel
            app.ValueSpinnerLabel = uilabel(app.minMaxDefPanel);
            app.ValueSpinnerLabel.HorizontalAlignment = 'right';
            app.ValueSpinnerLabel.Position = [2 120 36 15];
            app.ValueSpinnerLabel.Text = 'value';
            
            % Create ValueSpinner 
            if app.index == 10 && strcmpi(app.connctdShortCamNames{app.selectdev},'F200')%for white balance
                app.ValueSpinner = uispinner(app.minMaxDefPanel,'Step',100);
            elseif app.index == 10 %for white balance
                app.ValueSpinner = uispinner(app.minMaxDefPanel,'Step',10);
            else
                app.ValueSpinner = uispinner(app.minMaxDefPanel);
            end
            app.ValueSpinner.Limits = [app.minMaxDefaultStepValues(1) app.minMaxDefaultStepValues(2)];
            app.ValueSpinner.Value = app.SliderITSELF.Value;
            app.ValueSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ValueSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.ValueSpinner.Position = [(app.minMaxDefPanel.Position(3)-110) 115 100 22];
        end 
%>>>>> END of createSpinner

%>>>>>>>>>>  SliderValueChanged >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function SliderValueChanged(app,event) %updates spinner field
            SliderValueNotRoundedToTen = round(app.SliderITSELF.Value);
            
            if app.index ~= 10 % not whiteBalance
                app.ValueSpinner.Value = SliderValueNotRoundedToTen; %rounded to the nearest integer

            elseif app.index == 10 && strcmpi(app.connctdShortCamNames{app.selectdev},'F200') %%F200 and white balance                
                   % for white balance, rounds up to next 10th if LSD >= 5; else rounds down
                leastSiginicantDigit = rem(SliderValueNotRoundedToTen,10);%gets the least significatn digit
                if leastSiginicantDigit >= 5
                    while rem(leastSiginicantDigit,10) ~= 0
                        SliderValueNotRoundedToTen = SliderValueNotRoundedToTen + 1;
                        leastSiginicantDigit = leastSiginicantDigit + 1;
                    end
                else
                    while leastSiginicantDigit ~= 0
                        SliderValueNotRoundedToTen = SliderValueNotRoundedToTen - 1;
                        leastSiginicantDigit = leastSiginicantDigit - 1;
                    end
                end
                clear leastSignificantDigit;
                app.ValueSpinner.Value = SliderValueNotRoundedToTen;
            else %other cameras
                % for white balance, rounds up to next 10th if LSD >= 5; else rounds down
                leastSiginicantDigit = rem(SliderValueNotRoundedToTen,10);%gets the least significatn digit
                if leastSiginicantDigit >= 5
                    while rem(leastSiginicantDigit,10) ~= 0
                        SliderValueNotRoundedToTen = SliderValueNotRoundedToTen + 1;
                        leastSiginicantDigit = leastSiginicantDigit + 1;
                    end
                else
                    while leastSiginicantDigit ~= 0
                        SliderValueNotRoundedToTen = SliderValueNotRoundedToTen - 1;
                        leastSiginicantDigit = leastSiginicantDigit - 1;
                    end
                end
                clear leastSignificantDigit;
                app.ValueSpinner.Value = SliderValueNotRoundedToTen;
            end
            app.SliderITSELF.Value = app.ValueSpinner.Value;  
            app.dev_opt_current_value = SliderValueNotRoundedToTen; %updated value actually
            [~, app.err] = calllib('realsense', 'rs_set_device_option',...
                app.dev{app.selectdev}, app.opt, app.dev_opt_current_value, rs_error);
            rs_check_error(app.err);
            GetOptions(app);
            clear SliderValueNotRoundedToTen;
        end
%>>>>>>>>>>  END of SliderValueChanged

%>>>>>>>>>>  SpinnerValueChanged >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function SpinnerValueChanged(app,event) % updates slider with values from spinner
             app.SliderITSELF.Value = app.ValueSpinner.Value;
             app.dev_opt_current_value = app.SliderITSELF.Value;
             [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, app.opt, app.dev_opt_current_value, rs_error);
             rs_check_error(app.err);
             GetOptions(app);
        end
%>>>>>>>>>>  END of SpinnerValueChanged

%>>>>>>>>>>  SpinnerValueChanging >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function SpinnerValueChanging(app,event) % updates slider with values from spinner
                app.SliderITSELF.Value = event.Value;
        end
%>>>>>>>>>>  END of stepsDisplayed

%>>>>>>>>>>  SliderValueChanging >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>   
        function SliderValueChanging(app,event) %updates spinner field
            app.ValueSpinner.Value = event.Value;
            %app.ValueSpinner.Value = round(event.Value);
        end
%>>>>>>>>>>  END of SliderValueChanging
   
        function getCamShortName(app)
            allCamsConnected = strings(1,app.deviceCount);
            for i = 1:app.deviceCount %runs through al connected cameras
                newName = app.deviceFriendlyName(i); 
                newToken = strtok(newName); %first token
                while contains(newName,'Intel') || contains(newName,'RealSense')
                    newName =  erase(app.deviceFriendlyName(i),newToken); %removes 'Intel' and 'Realsense'
                    newToken =  strtok(newName);
                    newName = erase(newName,newToken);
                    %newToken = strtok(app.deviceFriendlyName(i))
                end
                allCamsConnected(i) = strtrim(newName); %short cam name
            end
            app.connctdShortCamNames = cellstr(allCamsConnected);
        end   
%>>>>>>>>> END of getCamShortName 

        function infrared_init(app,i)
                [~, app.err] = calllib('realsense', 'rs_enable_stream',...
                    app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_INFRARED, app.width, app.height,...
                    app.rsEnum.rs_format.RS_STREAM_INFRARED, app.fps, rs_error);
                rs_check_error(app.err);
                
                [~, app.err] = calllib('realsense', 'rs_enable_stream',...
                    app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_INFRARED, app.width, app.height,...
                    app.rsEnum.rs_format.RS_FORMAT_Y8, app.fps, rs_error);
                rs_check_error(app.err);
        end

% >>>>>>>>> testAndAddPathIfNeeded >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %changes folder if the currently selected folder isn't the app's one
        %also adds folder to path so other scripts can be executed
        function testAndChangePathIfNeeded(app)
            currentFolder = pwd;
           % appName = mfilename;
            appName = strcat({'\'},mfilename); % added for getting rid of slash on the next statement
                                                  %at the end of the full app path
            fullAppPath = mfilename('fullpath');
            app.appfullPath = erase(fullAppPath,appName);
            
            if ~strcmpi(currentFolder,app.appfullPath)
                %cd
                cd(app.appfullPath)
            end
            %addpath('colorcalibration');
            addpath('colorcalibration2');
        end
%>>>>>>>>> END of testAndAddPathIfNeeded        

        % Value changed function: ivcampresetDropDown
        function ivcampresetDropDownValueChanged(app, event)
            app.ivcamoptvals = cell2mat(app.ivcampresets.ivcampresets...
                (find(strcmp(app.ivcampresets.ivcampresets,...
                app.ivcampresetDropDown(app.selectdev).Value)), 2:16));
            setdeviceoptions(app);
            GetOptions(app);
        end

% >>>>>>>>> retrievePresentIvcamValues >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function retrievePresentIvcamValues(app)
            %app.selectdev
            currentIvcamValues = zeros(app.deviceCount,length(app.ivcamopts));
            indecesForIvcamValues = zeros(app.deviceCount,length(app.ivcamopts));
            
            for a=1:app.deviceCount
                firstColumnInOpt = cell2mat(app.OptTableComplete.(app.TabCamSelected(a).Title)(:,1));
                for i=1:length(app.ivcamopts)
                    [indecesForIvcamValues(a,i), ~] = find( firstColumnInOpt == (app.ivcamopts(i)+1) );%ivcam indeces follow the parameters' selection indeces (so  1 -> 0
                     
                end
            end
            %save('appdesigner.internal.model.AppModel/OptTableComplete.(app.TabCamSelected(1).Title)')
%             save('OptTableComplete.mat','app.OptTableComplete');
            
            for a=1:app.deviceCount
                for i=1:length(app.ivcamopts)
    	            currentIvcamValues(a,i) = transpose...
                                  (app.OptTableComplete.(app.TabCamSelected(a).Title)...
                                              {indecesForIvcamValues(a,i),6});%present parameters (column 6)
                end
            end

            for a=1:app.deviceCount
                for i=1:length(app.ivcampresets(1,2:end))
                    if isequal(app.ivcamoptvals{i,2:end},currentIvcamValues(a,2:end))
                        app.ivcampresetDropDown(a).Value = app.ivcamoptvals{i,1};
                    end	
                end
            end
        end

% >>>>>>>>> initTabGroup >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function initTabGroup(app)
                        % Create TabGroup
            app.TabGroup = uitabgroup(app.UIFigure);
            %app.TabGroup.AutoResizeChildren = 'on';
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @tabSelectedCallback, true);
            app.TabGroup.Position = [1 1 774 694];
            
        end
        
 % >>>>>>>>> tabSelectedCallback >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function tabSelectedCallback(app,event)
            %selecting new tab selects new device
            app.tabSelection = app.TabGroup.SelectedTab;
            
            for i=1:app.deviceCount
                if~isempty(strfind(app.TabCamSelected(i).Title,app.tabSelection.Title))
                    app.selectdev = i;%sets new camera selected
                end
            end
            %X by 10 CELL ARRAY, currently selected camera options table    
            
            app.presentCamSelecOptTable = app.OptTableComplete.(app.TabCamSelected(app.selectdev).Title);            
            selectedParameter(app, app.paramListBox(1).Value);
        end
 % >>>>>>>>> saveAsButtonButtonPushed >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function saveAsButtonButtonPushed(app,event)
            saveAllParameters(app);%saves all parameters to mat file
        end
% >>>>>>>>> loadSavedParamButtonPushed >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function loadSavedParamButtonPushed(app,event)
            loadAllParameters(app);
        end
% >>>>>>>>> colorstreamButtonValueChanged >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function colorstreamButtonValueChanged(app, event)
            RGB_title = app.TabCamSelected(app.selectdev).Title;
            RGB_title = ['RGB stream ',RGB_title];
            if ~isvalid(app.figcolor{app.selectdev})
                %if app.colorstreamButton.Value
                    app.figcolor{app.selectdev} = figure('Name',RGB_title);
                    app.figcolor{app.selectdev}.DeleteFcn = @delete_RGB;
                    app.axcolor{app.selectdev} = axes;
                    start(app.timercolor{app.selectdev});
                %end
            else
                stop(app.timercolor{app.selectdev});
                close(app.figcolor{app.selectdev});
                clear app.figcolor{app.selectdev};
            end
        end
    
        function delete_RGB(app, event)
            app.colorstreamButton.Value = 0;
            colorstreamButtonValueChanged(app,event);
            delete app.figcolor{app.selectdev};
            
        end
%% >>>>>>>>>>>>>>> TO BE ADDED IN THE FUTURE : RGB stream based on uifigure instead of GUIDE one
% 
%         function colorstreamButtonValueChanged(app, event)            
%             if app.colorstreamButton.Value
%                 app.RGBfig(app.selectdev) = uifigure('Name','RGB');
%                 RGB_title = [app.deviceFriendlyName{app.selectdev},...
%                     '_', num2str(app.selectdev) ];
% 
%                 app.axcolor = uiaxes(app.RGBfig(app.selectdev));
%                 app.axcolor.Title.String = RGB_title;
%                 
%                                % app.UIFigure = uifigure;
%                 app.RGBfig(app.selectdev).AutoResizeChildren = 'on';
%                 %app.RGBfig.Position = [100 100 1024 768];
%                 app.RGBfig(app.selectdev).Name = 'RGB stream';
%                 app.RGBfig(app.selectdev).Resize  = 'on';
%                 app.RGBfig(app.selectdev).CloseRequestFcn = createCallbackFcn(app, @RGBfigCloseRequest, true);
%     %            app.RGBfig.SizeChangedFcn = createCallbackFcn(app, @RGBfigSizeChanged, true);
%                 
%     %                 app.figcolor{app.selectdev} = figure('Name', 'RGB');
%                 
%             %app.axcolor  = app.figcolor{app.selectdev}.CurrentAxes;
%             
% %             app.axcolor  = app.figcolor{app.selectdev}.CurrentAxes;
% %             
%             start(app.timercolor{app.selectdev});
% %             
%             
%             %isempty(get(groot,'CurrentFigure')
% %             if ~isempty(app.axcolor)
% %                 app.axcolor.Units = 'pixels';
% %             end
% %                 if 
% %                     app.axcolor.Visible = 'off';
%             else
% %                 app.axcolor = '';
%                 stop(app.timercolor{app.selectdev});
%                 %close(app.figcolor{app.selectdev});
%             end
%         end


        %RGB figure callbacks
%         function RGBfigCloseRequest(app, event)
%             stop(app.timercolor{app.selectdev});
%             delete(app.RGBfig(app.selectdev));
%             
%         end
%         function RGBfigSizeChanged(app)
%         end
    
%       function deleteStream(app)
%             % Delete UIFigure when app is deleted
%             delete(app.UIFigure)
%         end
    end % END of PUBLIC methods


    methods (Access = private)

        % Callback function
        function allstreamButtonValueChanged(app, event)
            if app.allstreamButton.Value
                %create figure and divide into subaxes for each stream
                app.figall = figure('Name', 'All Cameras');
                %title('');
                for i = 1:2 * app.deviceCount
                    app.axall{i} = subplot(2, app.deviceCount, i);
                end
                start(app.timerall);
            else
                stop(app.timerall);
                close(app.figall);
            end
        end
    
    
        % Callback function
        function colorcalibrationButtonPushed(app, event)
            %app.figcalorig = figure('Name', 'Original');
            %app.axcalorig = axes;
            %app.figcalcorr = figure('Name', 'Corrected');
            %app.axcalcorr = axes;
            %calibrate(app);
            camColorCalibration_v2(app);
        end

        % Callback function
        function depthstreamButtonValueChanged(app, event)
            if app.depthstreamButton.Value
                app.figdepth = figure('Name', 'Depth');
                app.axdepth = axes;
                start(app.timerdepth);
            else
                stop(app.timerdepth);
                close(app.figdepth);
            end
        end

        % Callback function
        function pointcloudButtonValueChanged(app, event)
            if app.pointcloudButton.Value
                app.figpcl = figure('Name', 'Point Cloud');
                app.axpcl = axes;
                start(app.timerpcl);
            else
                stop(app.timerpcl);
                close(app.figpcl);
            end
        end

        % Callback function
        function selectedParameter(app, event)
            app.index = uint8(0);
            
            titles = app.presentCamSelecOptTable(:,4);%finds the selection and gets its index
            indeces = strfind(cellstr(titles),app.paramListBox(app.selectdev).Value,'ForceCellOutput',true); %test for finding string           
            app.index = find(~cellfun(@isempty,indeces)); %gets real index 1-69 of selection
            
            %use below to write real parameter's name
            app.functionCall =  cell2mat(app.presentCamSelecOptTable(app.index,3));
            app.opt = app.rsEnum.rs_option.(app.functionCall);
            buildSelection(app); %builds slider
            clear titles;
        end

        % Callback function
        function setallparameterstodefaultvaluesButtonPushed(app, event)
            for i = 1:length(app.presentCamSelecOptTable(:,8))
                [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, i - 1, cell2mat(app.presentCamSelecOptTable(i,8)), rs_error);
                rs_check_error(app.err);
            end
            GetOptions(app);
        end

        % Callback function
        function setselectiontodefaultButtonPushed(app, event)
            %why does this have a i-1 as the input of  
            [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, app.opt, app.minMaxDefaultStepValues(3), rs_error);
            rs_check_error(app.err);
            GetOptions(app);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% PROGRAM STARTUP  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            initTabGroup(app); % tabs for connected cams
            initializeVariables(app);
            if strcmpi(app.warningNoCam.Name,'Warning Dialog') 
                %delete(app);
                
                close(app.UIFigure);
                %UIFigureCloseRequest(app, 1);
            else %app keeps running
                close(app.warningNoCam);
                buildTabs(app);
                buildBottomButtons(app);
                buildivcamPanel(app);
                retrievePresentIvcamValues(app);
                app.TabGroup.SelectedTab = app.TabCamSelected(1);
                %    app.SR300ListBox.Value = app.SR300ListBox.Items(1);
                %    selectedParameter(app, app.SR300ListBox.Value); % starts with slider generated
                
                app.UIFigure.Position(3) = 774;
                app.UIFigure.Position(4) = app.listBoxMaxHeight + app.ivcamPanelHeight + 150;
                app.TabGroup.Position(4) = app.UIFigure.Position(4);
                
                %[1 1 774 694];
                 
                %app.presentCamSelecOptTable = app.selCamOptTable.(app.TabCamSelected(app.selectdev).Title);
                %app.tabSelection = app.TabGroup.SelectedTab;
                tabSelectedCallback(app,app.tabSelection);
                %tabHeight = app.UIFigure.Position
                
                %app.presentCamSelecOptTable = app.selCamOptTable.(app.TabCamSelected(app.selectdev).Title); %X by 10 CELL ARRAY
                %buildSelection(app);
                %selectedParameter(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          for i=1:app.deviceCount
                [app.ctx, app.err] = calllib('realsense', 'rs_disable_stream',...
                app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_COLOR, rs_error);
                rs_check_error(app.err);
                
                [app.ctx, app.err] = calllib('realsense', 'rs_disable_stream',...
                app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_DEPTH, rs_error);
                rs_check_error(app.err);                
                
                [~, app.err] = calllib('realsense', 'rs_stop_device',...
                app.dev{i}, rs_error);
                rs_check_error(app.err);      
                
                [~, app.err] = calllib('realsense', 'rs_delete_context', app.ctx, rs_error);
                rs_check_error(app.err);

          end
          clear realsense;
          delete(app);
          %warning('off','last');
          
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SR300_App_Beta1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end